classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        TempAxes                       matlab.ui.control.UIAxes
        CostAxes                       matlab.ui.control.UIAxes
        PowerAxes                      matlab.ui.control.UIAxes
        PropertiesPanel                matlab.ui.container.Panel
        RoomLabel                      matlab.ui.control.Label
        WindowsLabel                   matlab.ui.control.Label
        lengthEditFieldLabel           matlab.ui.control.Label
        RoomLength                     matlab.ui.control.NumericEditField
        PWallCheckBox                  matlab.ui.control.CheckBox
        widthEditFieldLabel            matlab.ui.control.Label
        RoomWidth                      matlab.ui.control.NumericEditField
        lengthEditField_2Label         matlab.ui.control.Label
        PWallLength                    matlab.ui.control.NumericEditField
        NumberofwindowsSpinnerLabel    matlab.ui.control.Label
        WindowsNum                     matlab.ui.control.Spinner
        heightEditField_2Label         matlab.ui.control.Label
        WindowsHeight                  matlab.ui.control.NumericEditField
        heightEditFieldLabel           matlab.ui.control.Label
        RoomHeight                     matlab.ui.control.NumericEditField
        widthEditField_3Label          matlab.ui.control.Label
        WindowsWidth                   matlab.ui.control.NumericEditField
        WallmaterialDropDownLabel      matlab.ui.control.Label
        WallMaterial                   matlab.ui.control.DropDown
        InsulationDropDownLabel        matlab.ui.control.Label
        Insulation                     matlab.ui.control.DropDown
        RoomAboveCheckBox              matlab.ui.control.CheckBox
        FloorSpinnerLabel              matlab.ui.control.Label
        Floor                          matlab.ui.control.Spinner
        StartButton                    matlab.ui.control.Button
        OutdoortemperatureSliderLabel  matlab.ui.control.Label
        OutdoorTempSlider              matlab.ui.control.Slider
        PlotsPanel                     matlab.ui.container.Panel
        ShowplotLabel                  matlab.ui.control.Label
        TempCheckBox                   matlab.ui.control.CheckBox
        CostsCheckBox                  matlab.ui.control.CheckBox
        PowerCheckBox                  matlab.ui.control.CheckBox
        TimePanel                      matlab.ui.container.Panel
        fromEditFieldLabel             matlab.ui.control.Label
        LowerFrom                      matlab.ui.control.NumericEditField
        LowerLabel                     matlab.ui.control.Label
        toEditFieldLabel               matlab.ui.control.Label
        LowerTo                        matlab.ui.control.NumericEditField
        EverydayCheckBox               matlab.ui.control.CheckBox
        DurationSliderLabel            matlab.ui.control.Label
        DurationSlider                 matlab.ui.control.Slider
        LowerLabel_2                   matlab.ui.control.Label
        RoomTempSlider                 matlab.ui.control.Slider
        LowerTempSlider                matlab.ui.control.Slider
        ShowsummaryButton              matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        Weather                        matlab.ui.container.Panel
        StationDropDownLabel           matlab.ui.control.Label
        Station                        matlab.ui.control.DropDown
        DateDatePickerLabel            matlab.ui.control.Label
        DateDatePicker                 matlab.ui.control.DatePicker
    end

    
    properties (Access = public)
        costs 
        uit 
        fig 
        size 
        % Description
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TempAxes.Visible = 'off';
            app.CostAxes.Visible = 'off';
            app.PowerAxes.Visible = 'off';
            
            app.PWallLength.Visible = 'off';
            app.lengthEditField_2Label.Visible = 'off';
            
            app.RoomTempSlider.Value = 21;
            app.LowerTempSlider.Value = 21;

            app.fig = figure('Visible',"off");
            app.uit = uitable(app.fig);
            app.uit.ColumnName = {'Room temp','Lower temp', 'Duration(h)','Size','Windows','Wall material','Insulation','Time(h)','Cost(zl)'};
            app.uit.ColumnWidth = {70,70,70,'auto',50,'auto','auto',55, 'auto'};
            app.uit.RowName = 'numbered';
            app.uit.Position = [15 100 650 300];
            
            
            %p = uidatepicker(app.Weather, 'DisplayFormat', 'yyyy-MM-dd', 'Limits',[datetime(2008,1,1) datetime('today')]);
            %p.Position(1:2) = [5 10];
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % Set temperature
            
            lower_from = app.LowerFrom.Value;
            lower_to = app.LowerTo.Value;
            
            if(app.EverydayCheckBox.Value == 1)
                assignin('base','modulo',24);
            else 
                assignin('base','modulo',app.DurationSlider.Value);
            end
            
            if(lower_from <= lower_to)
                assignin('base', 'lower_from', lower_from);
                assignin('base', 'lower_to', lower_to);
                assignin('base',"set_room", app.RoomTempSlider.Value);
                assignin('base',"set_lower", app.LowerTempSlider.Value);
                if(lower_from == lower_to)
                    assignin('base',"set_lower", app.RoomTempSlider.Value);
                end
            else
                assignin('base', 'lower_to', lower_from);
                assignin('base', 'lower_from', lower_to);
                assignin('base',"set_room", app.LowerTempSlider.Value);
                assignin('base',"set_lower", app.RoomTempSlider.Value);
            end

        
            % Room dimensions
            
            %length [m]
            length = app.RoomLength.Value;
            %width [m]
            width = app.RoomWidth.Value;
            %height [m]
            height = app.RoomHeight.Value;
            % radians to degrees
            %r2d = 180/pi;
            %roof pitch
            %roof_pitch = 40/r2d; 
            app.size = char(append(num2str(length) ,'x', num2str(width) ,'x', num2str(height)));
            
            
            % window area
            win_num = app.WindowsNum.Value;
            % Height of windows = 1 m
            win_height = app.WindowsHeight.Value;
            % Width of windows = 1 m
            win_width = app.WindowsWidth.Value;
            win_area = win_num * win_height * win_width;
           
            
            % wall area 
            plength = app.PWallLength.Value;
            wall_area = 2 * length * height + 2 * width * height - win_area - plength * height;


            % window resistance
            win_lambda = 0.78;  
            win_d = 0.01;        
            win_res = win_d /(win_lambda * 3600 * win_area);
                   
            % wall resistance            
            % (włokno szklane do ocieplenia budynku)
            % hour is the time unit
            % [k] = J/s/m/C 
            lambda = app.WallMaterial.Value;
            if(strcmp(lambda,"Cegła"))
                wall_lambda = 0.77;
            elseif(strcmp(lambda,"Pustak"))
                wall_lambda = 0.4;
            elseif(strcmp(lambda,"Żelbet"))
                wall_lambda = 1.7;
            else
                wall_lambda = 0.2;
            end
            
            wall_d = 0.2;
            wall_res = wall_d/wall_lambda;
            
            lambda_i = app.Insulation.Value;
            if(strcmp(lambda_i,"Brak"))
                ins_lambda = 0;
            elseif(strcmp(lambda_i,"Styropian"))
                ins_lambda = 0.04;
            elseif(strcmp(lambda_i,"Wełna mineralna"))
                ins_lambda = 0.18;
            else
                ins_lambda = 0.17;
            end
            
            % Heat flux density q [W/m^2]
            % q = U(Ti-Te)   U - material conductivity [W/(K*m^2)
            
            %convective heat transfer  
            if(ins_lambda ~= 0)
                ins_d = 0.1;
                ins_res = ins_d/ins_lambda;
                total_wall_res = (wall_res + ins_res)/(3600 * wall_area);
            else
                 total_wall_res = wall_res /(3600 * wall_area);
            end

            U = 1/win_res + 1/total_wall_res;
            
            if(app.RoomAboveCheckBox.Value == 0)
                ceiling_res = 0.3/(0.5 * 3600 * length*width);
                U = U + 1/ceiling_res; 
            end
            
            if(app.Floor.Value == 0)
                floor_res = 0.5/(1.5 * 3600 * length*width);
                U = U + 1/floor_res;
            end
            
            assignin('base','U',U);


            %density of air [kg/m^3]
            dens_air = 1.2250;
            %air mass
            M = length * width * height * dens_air;
            assignin('base','M',M);
            %cp of air (273 K) [J/kgK]
            c = 1005.4;
            assignin('base','c',c);
            %air flow rate [kg/hr]
            air_flow = 3600;
            assignin('base','air_flow',air_flow);
            
            % 1 kW-hr = 3.6e6 J
            % cost = 0.27 zl / 3.6e6 J
            cost = 0.27/3.6e6;
            assignin('base',"cost",cost);
            
            %Weather
            %{ 
                Krakow: 12566 
                Warszawa: 12372
                Gdansk: 12140
                Wroclaw: 12425
                Lodz: 12465
                Poznan: 12330
                Szczecin: 12205
                Bialystok: 12295
                Lublin: 12495
            %}
            
            which_station = app.Station.Value;
            if(strcmp(which_station,"Warsaw"))
                station = '12372';
            elseif(strcmp(which_station,"Gdansk"))
                station = '12140';
            elseif(strcmp(which_station,"Wroclaw"))
                station = '12425';
            elseif(strcmp(which_station,"Lodz"))
                station = '12465';
            elseif(strcmp(which_station,"Poznan"))
                station = '12330';
            elseif(strcmp(which_station,"Szczecin"))
                station = '12205';
            elseif(strcmp(which_station,"Bialystok"))
                station = '12295';
            elseif(strcmp(which_station,"Lublin"))
                station = '12495';
            else
                station = '12566';
            end
            
            formatOut = 'yyyy-mm-dd';
            start = app.DateDatePicker.Value;
            start_day = datestr(start, formatOut);
            
            how_long = app.DurationSlider.Value;
            add_days = fix(how_long/24);
            
            date = datenum(start_day);
            date= addtodate(date, add_days-1, 'day');
            end_day = datestr(date,formatOut);

            key = 'db57U7X7';
            options = weboptions('ContentType','json');
            url = ['https://api.meteostat.net/v1/history/hourly?station=', station, '&start=', start_day, '&end=', end_day, '&time_zone=Europe/Warsaw&time_format=Y-m-d%20H:i&key=',key];
            Current_Data = webread(url, options);

            ti = [0:how_long-1]';
            temp = [Current_Data.data.temperature]';
            temperature = [ti,temp];
            assignin('base', 'temperature', temperature);

            % Draw plots 
            %assignin('base','init_temp', app.OutdoorTempSlider.Value);
            simout = sim('model','StopTime',num2str(app.DurationSlider.Value));
            
            % Plot of temperature outisde and inside the room
            if(app.TempCheckBox.Value == 1)
                app.TempAxes.Visible = 'on';
                plot(app.TempAxes, simout.temp.Time ,simout.temp.Data);
                hold(app.TempAxes,'on');
                plot(app.TempAxes, simout.temp2.Time, simout.temp2.Data);
                hold(app.TempAxes,'off');
                app.TempAxes.YLim = [-inf,25];
            else 
                app.TempAxes.Visible = 'off';
                app.TempAxes.cla;
            end
            
            % Consumption cost plot
            if(app.CostsCheckBox.Value == 1)
                app.CostAxes.Visible = 'on';
                plot(app.CostAxes,simout.cost.Time, simout.cost.Data);
            else 
                app.CostAxes.Visible = 'off';
                app.CostAxes.cla;
            end
            
            % Plot of produced energy
            if(app.PowerCheckBox.Value == 1)
                app.PowerAxes.Visible = 'on';
                plot(app.PowerAxes, simout.power.Time,simout.power.Data);
            else
                app.PowerAxes.Visible = 'off';
                app.PowerAxes.cla;
            end
            
            app.costs = simout.cost.Data(end);
            
            
        end

        % Value changed function: PWallCheckBox
        function PWallCheckBoxValueChanged(app, event)
            value = app.PWallCheckBox.Value;
            if(value == 1)
                app.PWallLength.Visible = 'on';
                app.lengthEditField_2Label.Visible = 'on';
            else 
                app.PWallLength.Visible = 'off';
                app.lengthEditField_2Label.Visible = 'off';
                app.PWallLength.Value = 0;
            end
                   
        end

        % Button pushed function: ShowsummaryButton
        function ShowsummaryButtonPushed(app, event)
            
            %t = sortrows(t,'Cost');
            openfig('newout.fig','new','visible')
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            time = round(app.DurationSlider.Value);
            if(app.LowerFrom.Value <= app.LowerTo.Value)
                temp_diff = app.LowerTo.Value - app.LowerFrom.Value;
            else
                temp_diff = 24 - app.LowerFrom.Value + app.LowerTo.Value;
            end

            cData = {app.RoomTempSlider.Value, app.LowerTempSlider.Value, temp_diff, app.size, app.WindowsNum.Value, app.WallMaterial.Value, app.Insulation.Value, time, app.costs};
            old = get(app.uit, 'data');
            new = [old ; cData];
            set(app.uit, 'data', new);
            saveas(app.fig,'newout','fig');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.TempAxes = uiaxes(app.UIFigure);
            title(app.TempAxes, 'Temperature')
            xlabel(app.TempAxes, 'Time')
            ylabel(app.TempAxes, 'Temp')
            app.CostAxes = uiaxes(app.UIFigure);
            title(app.CostAxes, 'Cost')
            xlabel(app.CostAxes, 'Time')
            ylabel(app.CostAxes, 'Expense')
            app.PowerAxes = uiaxes(app.UIFigure);
            title(app.PowerAxes, 'Energy consumption')
            xlabel(app.PowerAxes, 'Time')
            ylabel(app.PowerAxes, 'Power')
            app.PropertiesPanel = uipanel(app.UIFigure);
            app.PropertiesPanel.Title = 'Properties';
            app.RoomLabel = uilabel(app.PropertiesPanel);
            app.RoomLabel.Text = 'Room dimensions';
            app.WindowsLabel = uilabel(app.PropertiesPanel);
            app.WindowsLabel.Text = 'Windows dimensions';
            app.lengthEditFieldLabel = uilabel(app.PropertiesPanel);
            app.lengthEditFieldLabel.Text = 'length';
            app.RoomLength = uieditfield(app.PropertiesPanel, 'numeric');
            app.RoomLength.Limits = [0 Inf];
            app.PWallCheckBox = uicheckbox(app.PropertiesPanel);
            app.PWallCheckBox.ValueChangedFcn = createCallbackFcn(app, @PWallCheckBoxValueChanged, true);
            app.PWallCheckBox.Text = 'Partition wall';
            app.widthEditFieldLabel = uilabel(app.PropertiesPanel);
            app.widthEditFieldLabel.Text = 'width';
            app.RoomWidth = uieditfield(app.PropertiesPanel, 'numeric');
            app.RoomWidth.Limits = [0 Inf];
            app.lengthEditField_2Label = uilabel(app.PropertiesPanel);
            app.lengthEditField_2Label.Text = 'length';
            app.PWallLength = uieditfield(app.PropertiesPanel, 'numeric');
            app.PWallLength.Limits = [0 Inf];
            app.NumberofwindowsSpinnerLabel = uilabel(app.PropertiesPanel);
            app.NumberofwindowsSpinnerLabel.Text = 'Number of windows';
            app.WindowsNum = uispinner(app.PropertiesPanel);
            app.WindowsNum.Limits = [0 Inf];
            app.heightEditField_2Label = uilabel(app.PropertiesPanel);
            app.heightEditField_2Label.Text = 'height';
            app.WindowsHeight = uieditfield(app.PropertiesPanel, 'numeric');
            app.WindowsHeight.Limits = [0 Inf];
            app.heightEditFieldLabel = uilabel(app.PropertiesPanel);
            app.heightEditFieldLabel.Text = 'height';
            app.RoomHeight = uieditfield(app.PropertiesPanel, 'numeric');
            app.RoomHeight.Limits = [0 Inf];
            app.widthEditField_3Label = uilabel(app.PropertiesPanel);
            app.widthEditField_3Label.Text = 'width';
            app.WindowsWidth = uieditfield(app.PropertiesPanel, 'numeric');
            app.WindowsWidth.Limits = [0 Inf];
            app.WallmaterialDropDownLabel = uilabel(app.PropertiesPanel);
            app.WallmaterialDropDownLabel.Text = 'Wall material';
            app.WallMaterial = uidropdown(app.PropertiesPanel);
            app.WallMaterial.Items = {'Cegła', 'Pustak', 'Żelbet', 'Bloczek z betonu komórkowego'};
            app.WallMaterial.Value = 'Cegła';
            app.InsulationDropDownLabel = uilabel(app.PropertiesPanel);
            app.InsulationDropDownLabel.Text = 'Insulation';
            app.Insulation = uidropdown(app.PropertiesPanel);
            app.Insulation.Items = {'Brak', 'Styropian', 'Styropian grafitowy', 'Wełna mineralna'};
            app.Insulation.Value = 'Brak';
            app.RoomAboveCheckBox = uicheckbox(app.PropertiesPanel);
            app.RoomAboveCheckBox.Text = 'There is a heated room above';
            app.FloorSpinnerLabel = uilabel(app.PropertiesPanel);
            app.FloorSpinnerLabel.Text = 'Floor';
            app.Floor = uispinner(app.PropertiesPanel);
            app.Floor.Limits = [0 20];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.OutdoortemperatureSliderLabel = uilabel(app.UIFigure);
            app.OutdoortemperatureSliderLabel.Text = {'Outdoor'; 'temperature'};
            app.OutdoorTempSlider = uislider(app.UIFigure);
            app.OutdoorTempSlider.Limits = [-30 15];
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.ShowplotLabel = uilabel(app.PlotsPanel);
            app.ShowplotLabel.Text = 'Show plot of:';
            app.TempCheckBox = uicheckbox(app.PlotsPanel);
            app.TempCheckBox.Text = 'Temperature';
            app.CostsCheckBox = uicheckbox(app.PlotsPanel);
            app.CostsCheckBox.Text = 'Costs';
            app.PowerCheckBox = uicheckbox(app.PlotsPanel);
            app.PowerCheckBox.Text = 'Power';
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'Time';
            app.fromEditFieldLabel = uilabel(app.TimePanel);
            app.fromEditFieldLabel.Text = 'from';
            app.LowerFrom = uieditfield(app.TimePanel, 'numeric');
            app.LowerLabel = uilabel(app.TimePanel);
            app.LowerLabel.Text = 'Set lower temperature';
            app.toEditFieldLabel = uilabel(app.TimePanel);
            app.toEditFieldLabel.Text = 'to';
            app.LowerTo = uieditfield(app.TimePanel, 'numeric');
            app.EverydayCheckBox = uicheckbox(app.TimePanel);
            app.EverydayCheckBox.Text = 'Everyday';
            app.DurationSliderLabel = uilabel(app.TimePanel);
            app.DurationSliderLabel.Text = 'Duration';
            app.DurationSlider = uislider(app.TimePanel);
            app.DurationSlider.Limits = [0 168];
            app.DurationSlider.MajorTicks = [0 24 48 72 96 120 144 168];
            app.DurationSlider.MinorTicks = [12 36 60 84 108 132 156];
            app.LowerLabel_2 = uilabel(app.TimePanel);
            app.LowerLabel_2.Text = {'Set room'; 'temperature'};
            app.RoomTempSlider = uislider(app.TimePanel);
            app.RoomTempSlider.Limits = [5 30];
            app.RoomTempSlider.Value = 5;
            app.LowerTempSlider = uislider(app.TimePanel);
            app.LowerTempSlider.Limits = [5 30];
            app.LowerTempSlider.Value = 5;
            app.ShowsummaryButton = uibutton(app.UIFigure, 'push');
            app.ShowsummaryButton.ButtonPushedFcn = createCallbackFcn(app, @ShowsummaryButtonPushed, true);
            app.ShowsummaryButton.Text = 'Show summary';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Weather = uipanel(app.UIFigure);
            app.Weather.Title = 'Weather';
            app.StationDropDownLabel = uilabel(app.Weather);
            app.StationDropDownLabel.Text = 'Station';
            app.Station = uidropdown(app.Weather);
            app.Station.Items = {'Cracow', 'Warsaw', 'Gdansk', 'Wroclaw', 'Lodz', 'Poznan', 'Szczecin', 'Bialystok', 'Lublin', ''};
            app.Station.Value = 'Cracow';
            app.DateDatePickerLabel = uilabel(app.Weather);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.Weather);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end